classdef OSCARDeltaV < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DeltaVAppUIFigure             matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        ResultsPanel                  matlab.ui.container.Panel
        ResultsTextArea               matlab.ui.control.TextArea
        Label_3                       matlab.ui.control.Label
        InputsPanel                   matlab.ui.container.Panel
        TargetMassEditField           matlab.ui.control.NumericEditField
        TargetMassLabel               matlab.ui.control.Label
        RAANdegLabel                  matlab.ui.control.Label
        RAANEditField                 matlab.ui.control.NumericEditField
        RAANChangeEditField           matlab.ui.control.NumericEditField
        NumberofManeuversEditFieldLabel_6  matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        PhasingChangeEditField        matlab.ui.control.NumericEditField
        NumberofManeuversEditFieldLabel_5  matlab.ui.control.Label
        AOPChangeEditField            matlab.ui.control.NumericEditField
        NumberofManeuversEditFieldLabel_4  matlab.ui.control.Label
        InclinationChangeEditField    matlab.ui.control.NumericEditField
        NumberofManeuversEditFieldLabel_3  matlab.ui.control.Label
        AltitudeChangeEditField       matlab.ui.control.NumericEditField
        NumberofManeuversEditFieldLabel_2  matlab.ui.control.Label
        NumberofManeuversEditField    matlab.ui.control.NumericEditField
        NumberofManeuversEditFieldLabel  matlab.ui.control.Label
        ParametertoCalculateDropDown  matlab.ui.control.DropDown
        ParametertoCalculateDropDownLabel  matlab.ui.control.Label
        MissionParametersLabel        matlab.ui.control.Label
        ArgumentofPerigeeEditField    matlab.ui.control.NumericEditField
        ArgumentofPerigeedegLabel     matlab.ui.control.Label
        InclinationEditField          matlab.ui.control.NumericEditField
        InclinationdegLabel           matlab.ui.control.Label
        OrbitAltitudeEditField        matlab.ui.control.NumericEditField
        OrbitalAltitudekmLabel        matlab.ui.control.Label
        IspEditField                  matlab.ui.control.NumericEditField
        ISPsLabel                     matlab.ui.control.Label
        FuelMassEditField             matlab.ui.control.NumericEditField
        FuelMasskgLabel               matlab.ui.control.Label
        TotalMassEditField            matlab.ui.control.NumericEditField
        Label_2                       matlab.ui.control.Label
        TotalMasskgLabel              matlab.ui.control.Label
        SpacecraftParametersLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        g0 = 9.80665;
        R_earth = 6378.137;
        mu_earth = 398600.4418;
        fuel_safety_factor = 1.25
    end


    methods (Access = private)
        function [n_man, deltaV_per, results_text] = calculateNumberOfManeuvers(app, deltaV_avail, h, v, dh, dinc, daop, dphase, draan)
    deltaV_single = app.estimateManeuverDeltaV(h, v, dh, dinc, daop, dphase, draan);
    n_man = floor(deltaV_avail / deltaV_single);
    
    if n_man < 1
        error('Insufficient delta-V for even one maneuver with these requirements!');
    end
    
    deltaV_per = zeros(n_man, 1);
    results_text = '';
    
    for i = 1:n_man
        h_current = h + (i-1) * dh;
        r_current = app.R_earth + h_current;
        v_current = sqrt(app.mu_earth / r_current);
        deltaV_per(i) = app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop, dphase, draan);
        results_text = [results_text sprintf('Maneuver %d: %.4f km/s (alt: %.2f km)\n', i, deltaV_per(i), h_current)];
    end
    
    results_text = [results_text sprintf('\nTotal delta-V used: %.4f km/s\n', sum(deltaV_per))];
    results_text = [results_text sprintf('Remaining delta-V: %.4f km/s', deltaV_avail - sum(deltaV_per))];
end

        function [dh_result, deltaV_per, results_text] = calculateAltitudeChange(app, deltaV_avail, n_man, h, v, dinc, daop, dphase, draan)
    dh_test = 0;
    dh_step = 10;
    
    for iter = 1:100
        total_dv = 0;
        for i = 1:n_man
            h_current = h + (i-1) * dh_test;
            r_current = app.R_earth + h_current;
            v_current = sqrt(app.mu_earth / r_current);
            total_dv = total_dv + app.estimateManeuverDeltaV(h_current, v_current, dh_test, dinc, daop, dphase, draan);
        end
        
        if abs(total_dv - deltaV_avail) < 0.001
            break;
        end
        
        if total_dv < deltaV_avail
            dh_test = dh_test + dh_step;
        else
            dh_test = dh_test - dh_step;
            dh_step = dh_step / 2;
        end
    end
    
    dh_result = dh_test;
    deltaV_per = zeros(n_man, 1);
    results_text = '';
    
    for i = 1:n_man
        h_current = h + (i-1) * dh_result;
        r_current = app.R_earth + h_current;
        v_current = sqrt(app.mu_earth / r_current);
        deltaV_per(i) = app.estimateManeuverDeltaV(h_current, v_current, dh_result, dinc, daop, dphase, draan);
        results_text = [results_text sprintf('Maneuver %d: %.4f km/s (alt: %.2f km)\n', i, deltaV_per(i), h_current)];
    end
    
    results_text = [results_text sprintf('\nTotal delta-V used: %.4f km/s', sum(deltaV_per))];
end

        function [dinc_result, deltaV_per, results_text] = calculateInclinationChange(app, deltaV_avail, n_man, h, v, dh, daop, dphase, draan)
    dinc_test = 0;
    dinc_step = 1;
    
    for iter = 1:100
        total_dv = 0;
        for i = 1:n_man
            h_current = h + (i-1) * dh;
            r_current = app.R_earth + h_current;
            v_current = sqrt(app.mu_earth / r_current);
            total_dv = total_dv + app.estimateManeuverDeltaV(h_current, v_current, dh, dinc_test, daop, dphase, draan);
        end
        
        if abs(total_dv - deltaV_avail) < 0.001
            break;
        end
        
        if total_dv < deltaV_avail
            dinc_test = dinc_test + dinc_step;
        else
            dinc_test = dinc_test - dinc_step;
            dinc_step = dinc_step / 2;
        end
    end
    
    dinc_result = dinc_test;
    deltaV_per = zeros(n_man, 1);
    results_text = '';
    
    for i = 1:n_man
        h_current = h + (i-1) * dh;
        r_current = app.R_earth + h_current;
        v_current = sqrt(app.mu_earth / r_current);
        deltaV_per(i) = app.estimateManeuverDeltaV(h_current, v_current, dh, dinc_result, daop, dphase, draan);
        results_text = [results_text sprintf('Maneuver %d: %.4f km/s\n', i, deltaV_per(i))];
    end
    
    results_text = [results_text sprintf('\nTotal delta-V used: %.4f km/s', sum(deltaV_per))];
end

        function [daop_result, deltaV_per, results_text] = calculateAOPChange(app, deltaV_avail, n_man, h, v, dh, dinc, dphase, draan)
    daop_test = 0;
    daop_step = 1;
    
    for iter = 1:100
        total_dv = 0;
        for i = 1:n_man
            h_current = h + (i-1) * dh;
            r_current = app.R_earth + h_current;
            v_current = sqrt(app.mu_earth / r_current);
            total_dv = total_dv + app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop_test, dphase, draan);
        end
        
        if abs(total_dv - deltaV_avail) < 0.001
            break;
        end
        
        if total_dv < deltaV_avail
            daop_test = daop_test + daop_step;
        else
            daop_test = daop_test - daop_step;
            daop_step = daop_step / 2;
        end
    end
    
    daop_result = daop_test;
    deltaV_per = zeros(n_man, 1);
    results_text = '';
    
    for i = 1:n_man
        h_current = h + (i-1) * dh;
        r_current = app.R_earth + h_current;
        v_current = sqrt(app.mu_earth / r_current);
        deltaV_per(i) = app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop_result, dphase, draan);
        results_text = [results_text sprintf('Maneuver %d: %.4f km/s\n', i, deltaV_per(i))];
    end
    
    results_text = [results_text sprintf('\nTotal delta-V used: %.4f km/s', sum(deltaV_per))];
end

        function [dphase_result, deltaV_per, results_text] = calculatePhasingChange(app, deltaV_avail, n_man, h, v, dh, dinc, daop, draan)
    dphase_test = 0;
    dphase_step = 1;
    
    for iter = 1:100
        total_dv = 0;
        for i = 1:n_man
            h_current = h + (i-1) * dh;
            r_current = app.R_earth + h_current;
            v_current = sqrt(app.mu_earth / r_current);
            total_dv = total_dv + app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop, dphase_test, draan);
        end
        
        if abs(total_dv - deltaV_avail) < 0.001
            break;
        end
        
        if total_dv < deltaV_avail
            dphase_test = dphase_test + dphase_step;
        else
            dphase_test = dphase_test - dphase_step;
            dphase_step = dphase_step / 2;
        end
    end
    
    dphase_result = dphase_test;
    deltaV_per = zeros(n_man, 1);
    results_text = '';
    
    for i = 1:n_man
        h_current = h + (i-1) * dh;
        r_current = app.R_earth + h_current;
        v_current = sqrt(app.mu_earth / r_current);
        deltaV_per(i) = app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop, dphase_result, draan);
        results_text = [results_text sprintf('Maneuver %d: %.4f km/s\n', i, deltaV_per(i))];
    end
    
    results_text = [results_text sprintf('\nTotal delta-V used: %.4f km/s', sum(deltaV_per))];
end

        function [draan_result, deltaV_per, results_text] = calculateRAANChange(app, deltaV_avail, n_man, h, v, dh, dinc, daop, dphase)
    draan_test = 0;
    draan_step = 1;
    
    for iter = 1:100
        total_dv = 0;
        for i = 1:n_man
            h_current = h + (i-1) * dh;
            r_current = app.R_earth + h_current;
            v_current = sqrt(app.mu_earth / r_current);
            total_dv = total_dv + app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop, dphase, draan_test);
        end
        
        if abs(total_dv - deltaV_avail) < 0.001
            break;
        end
        
        if total_dv < deltaV_avail
            draan_test = draan_test + draan_step;
        else
            draan_test = draan_test - draan_step;
            draan_step = draan_step / 2;
        end
    end
    
    draan_result = draan_test;
    deltaV_per = zeros(n_man, 1);
    results_text = '';
    
    for i = 1:n_man
        h_current = h + (i-1) * dh;
        r_current = app.R_earth + h_current;
        v_current = sqrt(app.mu_earth / r_current);
        deltaV_per(i) = app.estimateManeuverDeltaV(h_current, v_current, dh, dinc, daop, dphase, draan_result);
        results_text = [results_text sprintf('Maneuver %d: %.4f km/s\n', i, deltaV_per(i))];
    end
    
    results_text = [results_text sprintf('\nTotal delta-V used: %.4f km/s', sum(deltaV_per))];
end

        function deltaV = estimateManeuverDeltaV(app, h, v, dh, dinc, daop, dphase, draan)
    r = app.R_earth + h;
    
    % Altitude change component (Hohmann)
    if dh ~= 0
        r_new = r + dh;
        a_transfer = (r + r_new) / 2;
        v_transfer = sqrt(app.mu_earth * (2/r - 1/a_transfer));
        deltaV_altitude = abs(v - v_transfer);
    else
        deltaV_altitude = 0;
    end
    
    % Inclination change component
    deltaV_inclination = 2 * v * sin(deg2rad(dinc) / 2);
    
    deltaV_raan = v * deg2rad(draan) * sin(deg2rad(45));  % Assuming mid-range inclination
    
    % Argument of perigee change (simplified)
    deltaV_aop = v * deg2rad(daop) * 0.5;
    
    % Phasing change
    deltaV_phasing = v * deg2rad(dphase) / 360;
    
    % Combined delta-V (vector sum approximation)
    deltaV = sqrt(deltaV_altitude^2 + deltaV_inclination^2 + deltaV_aop^2 + deltaV_phasing^2 + deltaV_raan^2);
end
        
        function [results_text, deltaV_breakdown] = calculateTargetCaptureDeorbit(app, deltaV_avail, h_initial, m_spacecraft, m_fuel, Isp)
    % Calculate delta-V required for target capture and deorbit mission
    % Mission profile:
    %   0. (Optional) Plane change to target's orbit
    %   1. Rendezvous with target at h_initial
    %   2. Capture target (docking - minimal dV)
    %   3. Deorbit combined mass to 100 km
    %   4. Release target at 100 km
    %   5. Return to h_initial
    
    % Get target parameters
    m_target = app.TargetMassEditField.Value;
    
    % Get current spacecraft inclination
    inc_current = app.InclinationEditField.Value;
    

        inc_target = inc_current;  % Assume same plane, this is here in case I add target inclinations to the UI

    
    % Calculate plane change requirement
    dinc = abs(inc_target - inc_current);
    has_plane_change = (dinc > 0.01);  % More than 0.01 degrees
    
    % Constants
    r_initial = app.R_earth + h_initial;
    r_deorbit = app.R_earth + 100;
    
    % Initial orbital velocity
    v_initial = sqrt(app.mu_earth / r_initial);
    
    % Phase 0: Plane change (if needed)
    if has_plane_change
        deltaV_plane_change = 2 * v_initial * sin(deg2rad(dinc) / 2);
    else
        deltaV_plane_change = 0;
    end
    
    % Phase 1: Rendezvous (phasing and final approach)
    deltaV_rendezvous = 0.050;  % km/s - typical for phasing/final approach
    
    % Phase 2: Capture/Docking
    deltaV_capture = 0.002;  % km/s - gentle docking maneuver
    
    % Phase 3: Deorbit with target (Hohmann transfer down)
    m_combined = m_spacecraft + m_target;
    
    % Hohmann transfer to deorbit altitude
    a_transfer_down = (r_initial + r_deorbit) / 2;
    v_transfer_periapsis = sqrt(app.mu_earth * (2/r_initial - 1/a_transfer_down));
    
    % Delta-V to start descent (at initial altitude)
    deltaV_deorbit_burn1 = abs(v_initial - v_transfer_periapsis);
    
    % Phase 4: Release target at 100 km
    deltaV_release = 0;  % Just let go
    
    % Phase 5: Return to initial altitude (now without target)
    v_at_periapsis_deorbit = sqrt(app.mu_earth * (2/r_deorbit - 1/a_transfer_down));
    
    % Hohmann transfer back up
    a_transfer_up = (r_deorbit + r_initial) / 2;
    v_transfer_periapsis_return = sqrt(app.mu_earth * (2/r_deorbit - 1/a_transfer_up));
    
    % Delta-V to start ascent (at 100 km)
    deltaV_return_burn1 = abs(v_transfer_periapsis_return - v_at_periapsis_deorbit);
    
    % At apoapsis (initial altitude), circularize
    v_at_apoapsis = sqrt(app.mu_earth * (2/r_initial - 1/a_transfer_up));
    deltaV_return_burn2 = abs(v_initial - v_at_apoapsis);
    
    % Calculate fuel consumption for each phase using rocket equation
    m_current = m_spacecraft;
    
    % Phase 0: Plane change (if needed)
    if has_plane_change
        m_after_phase0 = m_current / exp(deltaV_plane_change * 1000 / (Isp * app.g0));
        m_fuel_phase0 = m_current - m_after_phase0;
        m_current = m_after_phase0;
    else
        m_fuel_phase0 = 0;
    end
    
    % Phase 1: Rendezvous
    m_after_phase1 = m_current / exp(deltaV_rendezvous * 1000 / (Isp * app.g0));
    m_fuel_phase1 = m_current - m_after_phase1;
    m_current = m_after_phase1;
    
    % Phase 2: Capture
    m_after_phase2 = m_current / exp(deltaV_capture * 1000 / (Isp * app.g0));
    m_fuel_phase2 = m_current - m_after_phase2;
    
    % Phase 3: Deorbit with target (combined mass)
    m_combined_start = m_after_phase2 + m_target;
    m_combined_after = m_combined_start / exp(deltaV_deorbit_burn1 * 1000 / (Isp * app.g0));
    m_fuel_phase3 = m_combined_start - m_combined_after;
    
    % Phase 4: Release (no fuel)
    m_fuel_phase4 = 0;
    m_spacecraft_after_release = m_combined_after - m_target;
    
    % Phase 5: Return without target
    deltaV_phase5 = deltaV_return_burn1 + deltaV_return_burn2;
    m_after_return = m_spacecraft_after_release / exp(deltaV_phase5 * 1000 / (Isp * app.g0));
    m_fuel_phase5 = m_spacecraft_after_release - m_after_return;
    
    % Total fuel required
    m_fuel_required = m_fuel_phase0 + m_fuel_phase1 + m_fuel_phase2 + m_fuel_phase3 + m_fuel_phase5;
    
    % Apply safety factor
    m_fuel_required_with_margin = m_fuel_required * app.fuel_safety_factor;
    
    % Check if we have enough fuel
    if m_fuel_required_with_margin > m_fuel
        results_text = sprintf('ERROR: Insufficient fuel!\n\n');
        results_text = [results_text sprintf('Fuel Required (with %.0f%% safety margin): %.2f kg\n', ...
            (app.fuel_safety_factor - 1) * 100, m_fuel_required_with_margin)];
        results_text = [results_text sprintf('Fuel Available: %.2f kg\n', m_fuel)];
        results_text = [results_text sprintf('Shortfall: %.2f kg\n\n', m_fuel_required_with_margin - m_fuel)];
        results_text = [results_text sprintf('--- MISSION BREAKDOWN (shows why insufficient) ---\n')];
        % Continue to show breakdown even on failure
    end
    
    % Build results text
    results_text_body = '';
    results_text_body = [results_text_body sprintf('=== TARGET CAPTURE & DEORBIT MISSION ===\n\n')];
    results_text_body = [results_text_body sprintf('TARGET PARAMETERS:\n')];
    results_text_body = [results_text_body sprintf('Target Mass: %.2f kg\n', m_target)];
    results_text_body = [results_text_body sprintf('Target Inclination: %.2f deg\n', inc_target)];
    results_text_body = [results_text_body sprintf('Spacecraft Inclination: %.2f deg\n', inc_current)];
    results_text_body = [results_text_body sprintf('Plane Change Required: %.2f deg\n', dinc)];
    results_text_body = [results_text_body sprintf('Combined Mass (S/C + Target): %.2f kg\n\n', m_combined)];
    results_text_body = [results_text_body sprintf('===============================\n')];
    results_text_body = [results_text_body sprintf('MISSION PHASES:\n\n')];
    
    % Phase 0: Plane change (if applicable)
    if has_plane_change
        results_text_body = [results_text_body sprintf('Phase 0: Plane Change\n')];
        results_text_body = [results_text_body sprintf('  Inclination Change: %.2f deg\n', dinc)];
        results_text_body = [results_text_body sprintf('  Delta-V: %.4f km/s\n', deltaV_plane_change)];
        results_text_body = [results_text_body sprintf('  Fuel Used: %.2f kg\n\n', m_fuel_phase0)];
    end
    
    results_text_body = [results_text_body sprintf('Phase 1: Rendezvous & Approach\n')];
    results_text_body = [results_text_body sprintf('  Delta-V: %.4f km/s\n', deltaV_rendezvous)];
    results_text_body = [results_text_body sprintf('  Fuel Used: %.2f kg\n\n', m_fuel_phase1)];
    
    results_text_body = [results_text_body sprintf('Phase 2: Capture/Docking\n')];
    results_text_body = [results_text_body sprintf('  Delta-V: %.4f km/s\n', deltaV_capture)];
    results_text_body = [results_text_body sprintf('  Fuel Used: %.2f kg\n\n', m_fuel_phase2)];
    
    results_text_body = [results_text_body sprintf('Phase 3: Deorbit to 100 km (with target)\n')];
    results_text_body = [results_text_body sprintf('  Delta-V: %.4f km/s\n', deltaV_deorbit_burn1)];
    results_text_body = [results_text_body sprintf('  Fuel Used: %.2f kg\n', m_fuel_phase3)];
    results_text_body = [results_text_body sprintf('  Mass Penalty: +%.0f%% due to target\n\n', (m_target / m_after_phase2) * 100)];
    
    results_text_body = [results_text_body sprintf('Phase 4: Release Target at 100 km\n')];
    results_text_body = [results_text_body sprintf('  Delta-V: %.4f km/s\n', deltaV_release)];
    results_text_body = [results_text_body sprintf('  Fuel Used: %.2f kg\n\n', m_fuel_phase4)];
    
    results_text_body = [results_text_body sprintf('Phase 5: Return to %.0f km (without target)\n', h_initial)];
    results_text_body = [results_text_body sprintf('  Burn (at %.0f km): %.4f km/s\n', h_initial, deltaV_return_burn2)];
    results_text_body = [results_text_body sprintf('  Total Delta-V: %.4f km/s\n', deltaV_phase5)];
    results_text_body = [results_text_body sprintf('  Fuel Used: %.2f kg\n\n', m_fuel_phase5)];
    results_text_body = [results_text_body sprintf('===============================\n')];
    results_text_body = [results_text_body sprintf('MISSION SUMMARY:\n')];
    results_text_body = [results_text_body sprintf('Total Fuel Required: %.2f kg\n', m_fuel_required)];
    results_text_body = [results_text_body sprintf('Safety Factor: %.2f (%.0f%% margin)\n', ...
        app.fuel_safety_factor, (app.fuel_safety_factor - 1) * 100)];
    results_text_body = [results_text_body sprintf('Fuel Required (w/ margin): %.2f kg (%.1f%%)\n', ...
    m_fuel_required_with_margin, (m_fuel_required_with_margin / m_fuel) * 100)];
    results_text_body = [results_text_body sprintf('Maximum Consecutive Missions: %d\n',floor(m_fuel / m_fuel_required_with_margin))];
    results_text_body = [results_text_body sprintf('Fuel Available: %.2f kg\n', m_fuel)];
    
    if m_fuel_required_with_margin <= m_fuel
        results_text_body = [results_text_body sprintf('Fuel Remaining: %.2f kg (%.1f%%)\n', ...
            m_fuel - m_fuel_required_with_margin, ((m_fuel - m_fuel_required_with_margin) / m_fuel) * 100)];
        results_text_body = [results_text_body sprintf('Final Spacecraft Mass: %.2f kg\n', m_after_return)];
        results_text_body = [results_text_body sprintf('\n✓ MISSION FEASIBLE\n')];
    else
        results_text_body = [results_text_body sprintf('Fuel Shortfall: %.2f kg\n', m_fuel_required_with_margin - m_fuel)];
        results_text_body = [results_text_body sprintf('\n✗ MISSION NOT FEASIBLE - INSUFFICIENT FUEL\n')];
    end
    
    % Combine error message (if any) with body
    if m_fuel_required_with_margin > m_fuel
        results_text = [results_text results_text_body];
    else
        results_text = results_text_body;
    end
    
    % Store breakdown for potential plotting
    deltaV_breakdown = struct(...
        'plane_change', deltaV_plane_change, ...
        'rendezvous', deltaV_rendezvous, ...
        'capture', deltaV_capture, ...
        'deorbit', deltaV_deorbit_burn1, ...
        'release', deltaV_release, ...
        'return', deltaV_phase5, ...
        'fuel_phase0', m_fuel_phase0, ...
        'fuel_phase1', m_fuel_phase1, ...
        'fuel_phase2', m_fuel_phase2, ...
        'fuel_phase3', m_fuel_phase3, ...
        'fuel_phase4', m_fuel_phase4, ...
        'fuel_phase5', m_fuel_phase5, ...
        'fuel_total', m_fuel_required, ...
        'fuel_with_margin', m_fuel_required_with_margin);
end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
        % Get input values
        m_total = app.TotalMassEditField.Value;
        m_fuel = app.FuelMassEditField.Value;
        Isp = app.IspEditField.Value;
        h_orbit = app.OrbitAltitudeEditField.Value;
        inc_current = app.InclinationEditField.Value;
        aop_current = app.ArgumentofPerigeeEditField.Value;
        raan_current = app.RAANEditField.Value; 
        
        % Validate inputs
        if m_fuel >= m_total
            app.ResultsTextArea.Value = {'Error: Fuel mass must be less than total mass!'};
            return;
        end
        
        if Isp <= 0 || m_total <= 0 || m_fuel <= 0
            app.ResultsTextArea.Value = {'Error: Mass and Isp must be positive values!'};
            return;
        end
        
        % Calculate total available delta-V
        mass_ratio = m_total / (m_total - m_fuel);
        deltaV_total = Isp * app.g0 * log(mass_ratio) / 1000; % Convert to km/s
        
        % Calculate deorbit delta-V (from current orbit to 100 km)
        r_current = app.R_earth + h_orbit;
        v_current = sqrt(app.mu_earth / r_current);
        r_deorbit = app.R_earth + 100;
        
        % Hohmann transfer to deorbit altitude
        a_transfer = (r_current + r_deorbit) / 2;
        v_transfer_at_current = sqrt(app.mu_earth * (2/r_current - 1/a_transfer));
        deltaV_deorbit = abs(v_current - v_transfer_at_current);
        
        % Available delta-V for maneuvers
        deltaV_available = deltaV_total - deltaV_deorbit;
        
        if deltaV_available <= 0
            app.ResultsTextArea.Value = {sprintf('Error: Insufficient delta-V!'), ...
                sprintf('Total delta-V: %.4f km/s', deltaV_total), ...
                sprintf('Deorbit delta-V: %.4f km/s', deltaV_deorbit)};
            return;
        end
        
        % Get requirements
        param_to_calc = app.ParametertoCalculateDropDown.Value;
        n_maneuvers = app.NumberofManeuversEditField.Value;
        dh_per = app.AltitudeChangeEditField.Value;
        dinc_per = app.InclinationChangeEditField.Value;
        daop_per = app.AOPChangeEditField.Value;
        dphase_per = app.PhasingChangeEditField.Value;
        draan_per = app.RAANChangeEditField.Value;
        
        % Calculate based on selected parameter
switch param_to_calc
    case 'Number of Maneuvers'
        [n_maneuvers, ~, results_text] = app.calculateNumberOfManeuvers(...
            deltaV_available, h_orbit, v_current, dh_per, dinc_per, daop_per, dphase_per, draan_per);
        
    case 'Altitude Change'
        [dh_per, ~, results_text] = app.calculateAltitudeChange(...
            deltaV_available, n_maneuvers, h_orbit, v_current, dinc_per, daop_per, dphase_per, draan_per);
        
    case 'Inclination Change'
        [dinc_per, ~, results_text] = app.calculateInclinationChange(...
            deltaV_available, n_maneuvers, h_orbit, v_current, dh_per, daop_per, dphase_per, draan_per);
        
    case 'Argument of Perigee Change'
        [daop_per, ~, results_text] = app.calculateAOPChange(...
            deltaV_available, n_maneuvers, h_orbit, v_current, dh_per, dinc_per, dphase_per, draan_per);
        
    case 'Phasing Change'
        [dphase_per, ~, results_text] = app.calculatePhasingChange(...
            deltaV_available, n_maneuvers, h_orbit, v_current, dh_per, dinc_per, daop_per, draan_per);
        
    case 'RAAN Change'
        [draan_per, ~, results_text] = app.calculateRAANChange(...
            deltaV_available, n_maneuvers, h_orbit, v_current, dh_per, dinc_per, daop_per, dphase_per);
    case 'Target Capture & Deorbit'
        [results_text, ~] = app.calculateTargetCaptureDeorbit(...
            deltaV_available, h_orbit, m_total, m_fuel, Isp);
        output = {};
        output{end+1} = '=== MISSION SUMMARY ===';
        output{end+1} = '';
        output{end+1} = 'SPACECRAFT PARAMETERS:';
        output{end+1} = sprintf('Total Mass: %.2f kg', m_total);
        output{end+1} = sprintf('Fuel Mass: %.2f kg', m_fuel);
        output{end+1} = sprintf('Specific Impulse: %.2f s', Isp);
        output{end+1} = '';
        output{end+1} = 'INITIAL ORBIT:';
        output{end+1} = sprintf('Altitude: %.2f km', h_orbit);
        output{end+1} = sprintf('Inclination: %.2f deg', inc_current);
        output{end+1} = '';
        results_lines = strsplit(results_text, '\n');
        output = [output, results_lines];
        app.ResultsTextArea.Value = output;
        return;
end
        
        % Display results
        output = {};
        output{end+1} = '=== MISSION SUMMARY ===';
        output{end+1} = '';
        output{end+1} = 'SPACECRAFT PARAMETERS:';
        output{end+1} = sprintf('Total Mass: %.2f kg', m_total);
        output{end+1} = sprintf('Fuel Mass: %.2f kg', m_fuel);
        output{end+1} = sprintf('Specific Impulse: %.2f s', Isp);
        output{end+1} = sprintf('Mass Ratio: %.4f', mass_ratio);
        output{end+1} = sprintf('Fuel Ratio: %.4f', (1/mass_ratio));
        output{end+1} = '';
        output{end+1} = 'INITIAL ORBIT:';
        output{end+1} = sprintf('Altitude: %.2f km', h_orbit);
        output{end+1} = sprintf('Inclination: %.2f deg', inc_current);
        output{end+1} = sprintf('Argument of Perigee: %.2f deg', aop_current);
        output{end+1} = sprintf('RAAN: %.2f deg', raan_current);
        output{end+1} = '';
        output{end+1} = 'DELTA-V BUDGET:';
        output{end+1} = sprintf('Total Available: %.4f km/s', deltaV_total);
        output{end+1} = sprintf('Deorbit (to 100 km): %.4f km/s', deltaV_deorbit);
        output{end+1} = sprintf('Available for Manuevers: %.4f km/s', deltaV_available);
        output{end+1} = sprintf('Safety Factor: %.2f', app.fuel_safety_factor);
        output{end+1} = '';
        output{end+1} = 'MISSION PROFILE:';
        output{end+1} = sprintf('Number of Maneuvers: %d', round(n_maneuvers));
        output{end+1} = sprintf('Altitude Change per Maneuver: %.4f km', dh_per);
        output{end+1} = sprintf('Inclination Change per Maneuver: %.4f deg', dinc_per);
        output{end+1} = sprintf('AOP Change per Maneuver: %.4f deg', daop_per);
        output{end+1} = sprintf('Phasing Change per Maneuver: %.4f deg', dphase_per);
        output{end+1} = sprintf('RAAN Change per Maneuver: %.4f deg', draan_per); 
        output{end+1} = '';
        output{end+1} = 'MANEUVER BREAKDOWN:';
        
        % Add results text
        results_lines = strsplit(results_text, '\n');
        output = [output, results_lines];
        
        app.ResultsTextArea.Value = output;
        
    catch ME
        app.ResultsTextArea.Value = {sprintf('Error: %s', ME.message)};
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DeltaVAppUIFigure = uifigure('Visible', 'off');
            app.DeltaVAppUIFigure.Name = 'DeltaVApp';
            app.GridLayout = uigridlayout(app.DeltaVAppUIFigure);
            app.InputsPanel = uipanel(app.GridLayout);
            app.InputsPanel.Title = 'Inputs';
            app.SpacecraftParametersLabel = uilabel(app.InputsPanel);
            app.SpacecraftParametersLabel.Text = 'Spacecraft Parameters';
            app.TotalMasskgLabel = uilabel(app.InputsPanel);
            app.TotalMasskgLabel.Text = 'Total Mass (kg)';
            app.Label_2 = uilabel(app.InputsPanel);
            app.Label_2.Text = '';
            app.TotalMassEditField = uieditfield(app.InputsPanel, 'numeric');
            app.TotalMassEditField.Value = 980;
            app.FuelMasskgLabel = uilabel(app.InputsPanel);
            app.FuelMasskgLabel.Text = 'Fuel Mass (kg)';
            app.FuelMassEditField = uieditfield(app.InputsPanel, 'numeric');
            app.FuelMassEditField.Value = 480;
            app.ISPsLabel = uilabel(app.InputsPanel);
            app.ISPsLabel.Text = 'ISP (s)';
            app.IspEditField = uieditfield(app.InputsPanel, 'numeric');
            app.IspEditField.Value = 1800;
            app.OrbitalAltitudekmLabel = uilabel(app.InputsPanel);
            app.OrbitalAltitudekmLabel.Text = 'Orbital Altitude (km)';
            app.OrbitAltitudeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.OrbitAltitudeEditField.Value = 550;
            app.InclinationdegLabel = uilabel(app.InputsPanel);
            app.InclinationdegLabel.Text = 'Inclination (deg)';
            app.InclinationEditField = uieditfield(app.InputsPanel, 'numeric');
            app.InclinationEditField.Value = 51.3;
            app.ArgumentofPerigeedegLabel = uilabel(app.InputsPanel);
            app.ArgumentofPerigeedegLabel.Text = 'Argument of Perigee (deg)';
            app.ArgumentofPerigeeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.MissionParametersLabel = uilabel(app.InputsPanel);
            app.MissionParametersLabel.Text = 'MissionParameters';
            app.ParametertoCalculateDropDownLabel = uilabel(app.InputsPanel);
            app.ParametertoCalculateDropDownLabel.Text = 'Parameter to Calculate';
            app.ParametertoCalculateDropDown = uidropdown(app.InputsPanel);
            app.ParametertoCalculateDropDown.Items = {'Number of Maneuvers', 'Altitude Change', 'Inclination Change', 'Argument of Perigee Change', 'Phasing Change', 'RAAN Change', 'Target Capture & Deorbit'};
            app.ParametertoCalculateDropDown.Value = 'Number of Maneuvers';
            app.NumberofManeuversEditFieldLabel = uilabel(app.InputsPanel);
            app.NumberofManeuversEditFieldLabel.Text = 'Number of Maneuvers';
            app.NumberofManeuversEditField = uieditfield(app.InputsPanel, 'numeric');
            app.NumberofManeuversEditField.Value = 5;
            app.NumberofManeuversEditFieldLabel_2 = uilabel(app.InputsPanel);
            app.NumberofManeuversEditFieldLabel_2.Text = 'Altitude Change (km/maneuver)';
            app.AltitudeChangeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.AltitudeChangeEditField.Value = 10;
            app.NumberofManeuversEditFieldLabel_3 = uilabel(app.InputsPanel);
            app.NumberofManeuversEditFieldLabel_3.Text = 'Inclination Change (deg/maneuver)';
            app.InclinationChangeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.InclinationChangeEditField.Value = 1;
            app.NumberofManeuversEditFieldLabel_4 = uilabel(app.InputsPanel);
            app.NumberofManeuversEditFieldLabel_4.Text = 'AOP Change (deg/maneuver)';
            app.AOPChangeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.AOPChangeEditField.Value = 1;
            app.NumberofManeuversEditFieldLabel_5 = uilabel(app.InputsPanel);
            app.NumberofManeuversEditFieldLabel_5.Text = 'Phasing Change (deg/maneuver)';
            app.PhasingChangeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.PhasingChangeEditField.Value = 60;
            app.CalculateButton = uibutton(app.InputsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NumberofManeuversEditFieldLabel_6 = uilabel(app.InputsPanel);
            app.NumberofManeuversEditFieldLabel_6.Text = 'RAAN (deg/maneuver)';
            app.RAANChangeEditField = uieditfield(app.InputsPanel, 'numeric');
            app.RAANChangeEditField.Value = 1;
            app.RAANEditField = uieditfield(app.InputsPanel, 'numeric');
            app.RAANdegLabel = uilabel(app.InputsPanel);
            app.RAANdegLabel.Text = 'RAAN (deg)';
            app.TargetMassLabel = uilabel(app.InputsPanel);
            app.TargetMassLabel.Text = 'Target Mass';
            app.TargetMassEditField = uieditfield(app.InputsPanel, 'numeric');
            app.ResultsPanel = uipanel(app.GridLayout);
            app.ResultsPanel.Title = 'Results';
            app.Label_3 = uilabel(app.ResultsPanel);
            app.Label_3.Text = '';
            app.ResultsTextArea = uitextarea(app.ResultsPanel);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextArea.Value = {'Click "Calculate" to see results'};
            % Show the figure after all components are created
            app.DeltaVAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSCARDeltaV

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DeltaVAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DeltaVAppUIFigure)
        end
    end
end